classdef Glaucoma_diagnosis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TipodeImagenIncorrectoLabel  matlab.ui.control.Label
        OclumTechLabel               matlab.ui.control.Label
        gifim                        matlab.ui.control.Image
        Image2                       matlab.ui.control.Image
        UDIButton                    matlab.ui.control.Button
        ImagenoriginalLabel          matlab.ui.control.Label
        ElegirimagenButton           matlab.ui.control.Button
        ProbarimagenaleatoriaButton  matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Lamp                         matlab.ui.control.Lamp
        CalidaddelaimagenLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        BuenacalidadBorrosaBajocontrasteRuidoLabel  matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        ImagenGuardadaLabel          matlab.ui.control.Label
        gifcrop                      matlab.ui.control.Image
        Panel_3                      matlab.ui.container.Panel
        GuardarimagencomoLabel       matlab.ui.control.Label
        GuardarButton                matlab.ui.control.Button
        GLAUCOMALabel                matlab.ui.control.Label
        UITable                      matlab.ui.control.Table
        ImagenrecortadaLabel         matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImOr % Imagen original
        ImProc % Imagen procesada
        model %modelo de prediccion calidad
        modelglau %modelo de prediccion glaucoma
        vars %variables para prediccion de glaucomas
        imdata %imagenes para random
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load Modelo_final_SVM.mat
            app.model=svm_final_mdl;
            load RFA_ENSTrees_model.mat
            app.modelglau=bestmdl;
            app.vars=varidx;
            load imagenes_limpias_predict.mat
            app.imdata=imlimpiaspredict;
            app.Lamp.Color=[1,1,0];
            app.Image.Visible=false;
            app.GuardarimagencomoLabel.Visible="off";
            app.GuardarButton.Visible="off";
            app.GLAUCOMALabel.Visible="off";
            axis(app.UIAxes, 'tight');
            axis(app.UIAxes_2, 'tight');
        end

        % Button pushed function: ProbarimagenaleatoriaButton
        function Test(app, event)
            app.TipodeImagenIncorrectoLabel.Visible="off";
            app.GLAUCOMALabel.Visible="off";
            
            I = imagenlimpiarandom(app.imdata);
            app.Lamp.Color=[0,1,0];
            app.Image.Position=[30,46,37,36];
            app.Image.Visible=true;
            
            app.gifim.Visible="on";
            app.gifcrop.Visible="on";
            figure(app.UIFigure);
            pause(0.1);
            imshow(I, 'Parent', app.UIAxes);
            app.UIAxes.Visible=true;
            app.gifim.Visible="off";
            figure(app.UIFigure);
            pause(0.1);
            
            % Guardar la imagen
            app.ImOr = I; 
            
            %Segmentador y features para detector de glaucomas
            [Imfeatures, cropped_image, edges]=get1ImageFeatures(I);
            edgesdilate=imdilate(edges,strel('disk',2));
            croppedim1=cropped_image;
            croppedimred=croppedim1(:,:,1);
            croppedimgreen=croppedim1(:,:,2);
            croppedimblue=croppedim1(:,:,3);
            croppedimred(edgesdilate==1)=0;
            croppedimgreen(edgesdilate==1)=255;
            croppedimblue(edgesdilate==1)=0;
            croppedim1(:,:,1)=croppedimred;
            croppedim1(:,:,2)=croppedimgreen;
            croppedim1(:,:,3)=croppedimblue;
            imshow(croppedim1, 'Parent', app.UIAxes_2);
            app.UIAxes_2.Visible=true;
            app.gifcrop.Visible="off";
            
            app.ImProc=croppedim1;
            
            app.UITable.Visible=true;
            app.UITable.Data=[Imfeatures(1,5),Imfeatures(1,3)];
            app.GuradarimagencomoLabel.Visible="on";
            app.GuardarButton.Visible="on";

            [class2, ~] = predict(app.modelglau, Imfeatures(app.vars));
            if class2==0
                app.GLAUCOMALabel.Text="No Glaucoma detected";
                app.GLAUCOMALabel.Visible="on";
            else
                app.GLAUCOMALabel.Text="GLAUCOMA DETECTED";
                app.GLAUCOMALabel.Visible="on";
            end
        end

        % Button pushed function: ElegirimagenButton
        function ImageSelected(app, event)
            app.TipodeImagenIncorrectoLabel.Visible="off";
            app.GLAUCOMALabel.Visible="off";
            % Mostrar diálogo para seleccionar la imagen
            [filename, pathname] = uigetfile({'*.jpg;*.png;', 'Imagenes (*.jpg, *.png)'}, 'Seleccionar Imagen');
            % Verificar si el usuario canceló la selección
            if isequal(filename, 0)
                figure(app.UIFigure);
                return; % No hacer nada si el usuario cancela la selección
            end
            % Concatenar la ruta completa del archivo
            app.UIAxes.Visible=false;
            app.UIAxes_2.Visible="off";
            fullpath = fullfile(pathname, filename);
            % Cargar la imagen seleccionada por el usuario
            I = imread(fullpath);
            app.gifim.Visible="on";
            app.gifcrop.Visible="on";
            figure(app.UIFigure);
            pause(0.1);
            imshow(I, 'Parent', app.UIAxes);
            app.UIAxes.Visible=true;
            app.gifim.Visible="off";
            pause(0.1);
            
            siz=size(I);
            if siz(1)<1080 || siz(2)<1080 || siz(3)~=3
                app.gifcrop.Visible="off";
                app.TipodeImagenIncorrectoLabel.Visible="on";
                return;
            end

            %Sacar features y pasar por el modelo
            Features=one_image_features(I);
            [class, ~] = predict(app.model, Features);
            
            if class == 4 % GOOD QUALITY CLASS
               app.Lamp.Color=[0,1,0];
               app.Image.Position=[47,46,37,36];
               app.Image.Visible=true;
               figure(app.UIFigure);
               pause(0.1);
               figure(app.UIFigure);
               
               % Guardar la imagen
               app.ImOr = I; 
    
               %Segmentador y features para detector de glaucomas
               [Imfeatures, cropped_image, edges]=get1ImageFeatures(I);
               edgesdilate=imdilate(edges,strel('disk',2));
               croppedim1=cropped_image;
               croppedimred=croppedim1(:,:,1);
               croppedimgreen=croppedim1(:,:,2);
               croppedimblue=croppedim1(:,:,3);
               croppedimred(edgesdilate==1)=0;
               croppedimgreen(edgesdilate==1)=255;
               croppedimblue(edgesdilate==1)=0;
               croppedim1(:,:,1)=croppedimred;
               croppedim1(:,:,2)=croppedimgreen;
               croppedim1(:,:,3)=croppedimblue;
               imshow(croppedim1, 'Parent', app.UIAxes_2);
               app.UIAxes_2.Visible=true;
               app.gifcrop.Visible="off";
               
               app.ImProc=croppedim1;
               
               app.UITable.Visible=true;
               app.UITable.Data=[Imfeatures(1,5),Imfeatures(1,3)];
               app.GuardarimagencomoLabel.Visible="on";
               app.GuardarButton.Visible="on";

               [class2, ~] = predict(app.modelglau, Imfeatures(app.vars));
               if class2==0
                   app.GLAUCOMALabel.Text="No Glaucoma detected";
                   app.GLAUCOMALabel.Visible="on";
               else
                   app.GLAUCOMALabel.Text="GLAUCOMA DETECTED";
                   app.GLAUCOMALabel.Visible="on";
               end

            else
                app.Lamp.Color=[1,0,0];
                app.Image.Visible=true;
                figure(app.UIFigure);
                if class==1 % LOW CONTRAST CLASS
                    app.Image.Position=[47,20,37,36];
                    
                elseif class==2 % BLURRED CLASS
                    app.Image.Position=[47,33,37,36];
                elseif class==3 % NOISY CLASS
                    app.Image.Position=[47,7,37,36];
                end
                app.GLAUCOMALabel.Visible=true;
                app.UIAxes_2.Visible="off";
                app.GuradarimagencomoLabel.Visible="off";
                app.GuardarButton.Visible="off";
            end
            app.gifim.Visible="off";
            app.gifcrop.Visible="off";
        end

        % Button pushed function: UDIButton
        function UDIButtonPushed(app, event)
            Infoapp;
        end

        % Button pushed function: GuardarButton
        function Saveimage(app, event)
            % imageName=string(app.GuradarimagencomoEditField.Value);
            % 
            % % Replace invalid filename characters with an underscore or other valid character
            % invalidChars = {'/', '\', ':', '*', '?', '"', '<', '>', '|','.'};
            % for k = 1:length(invalidChars)
            %     imageName = strrep(imageName, invalidChars{k}, '_');
            % end
            % imageName = strcat(imageName, '.png');
            
            [file, path] = uiputfile({'*.png';'*.jpg'}, 'Guardar imagen como');
    
            % Verificar si el usuario canceló la selección
            if isequal(file, 0)
                return;
            end

            % Define path
            filePath = fullfile(path, file);
            
            imwrite(app.ImProc, filePath);
            
            pause(0.1);
            app.ImagenGuardadaLabel.Visible = "on";
            pause(2);
            app.ImagenGuardadaLabel.Visible = "off";

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.UIAxes.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.ImagenrecortadaLabel = uilabel(app.Panel_2);
            app.ImagenrecortadaLabel.Text = 'Imagen recortada';
            app.Panel_3 = uipanel(app.Panel_2);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'ISNT'; 'Ratio Copa/Disco'};
            app.UITable.RowName = {};
            app.GLAUCOMALabel = uilabel(app.Panel_3);
            app.GLAUCOMALabel.Visible = 'off';
            app.GLAUCOMALabel.Text = 'GLAUCOMA';
            app.GuardarButton = uibutton(app.Panel_3, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @Saveimage, true);
            app.GuardarButton.Visible = 'off';
            app.GuardarButton.Text = 'Guardar';
            app.GuardarimagencomoLabel = uilabel(app.Panel_3);
            app.GuardarimagencomoLabel.Visible = 'off';
            app.GuardarimagencomoLabel.Text = 'Guardar imagen como';
            app.gifcrop = uiimage(app.Panel_2);
            app.gifcrop.Visible = 'off';
            app.gifcrop.ImageSource = fullfile(pathToMLAPP, 'yy3.gif');
            app.ImagenGuardadaLabel = uilabel(app.Panel_2);
            app.ImagenGuardadaLabel.Visible = 'off';
            app.ImagenGuardadaLabel.Text = 'Imagen Guardada!';
            app.Panel = uipanel(app.UIFigure);
            app.BuenacalidadBorrosaBajocontrasteRuidoLabel = uilabel(app.Panel);
            app.BuenacalidadBorrosaBajocontrasteRuidoLabel.Text = {'Buena calidad'; 'Borrosa'; 'Bajo contraste'; 'Ruido'; ''};
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'Red-Arrow-Png-195.png';
            app.CalidaddelaimagenLabel = uilabel(app.Panel);
            app.CalidaddelaimagenLabel.Text = 'Calidad de la imagen';
            app.Lamp = uilamp(app.Panel);
            app.ProbarimagenaleatoriaButton = uibutton(app.UIFigure, 'push');
            app.ProbarimagenaleatoriaButton.ButtonPushedFcn = createCallbackFcn(app, @Test, true);
            app.ProbarimagenaleatoriaButton.Text = 'Probar imagen aleatoria';
            app.ElegirimagenButton = uibutton(app.UIFigure, 'push');
            app.ElegirimagenButton.ButtonPushedFcn = createCallbackFcn(app, @ImageSelected, true);
            app.ElegirimagenButton.Text = 'Elegir imagen';
            app.ImagenoriginalLabel = uilabel(app.UIFigure);
            app.ImagenoriginalLabel.Text = 'Imagen original';
            app.UDIButton = uibutton(app.UIFigure, 'push');
            app.UDIButton.ButtonPushedFcn = createCallbackFcn(app, @UDIButtonPushed, true);
            app.UDIButton.Text = {'UDI'; ''};
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'unnamed.png';
            app.gifim = uiimage(app.UIFigure);
            app.gifim.Visible = 'off';
            app.gifim.ImageSource = fullfile(pathToMLAPP, 'yy3.gif');
            app.OclumTechLabel = uilabel(app.UIFigure);
            app.OclumTechLabel.Text = 'OclumTech';
            app.TipodeImagenIncorrectoLabel = uilabel(app.UIFigure);
            app.TipodeImagenIncorrectoLabel.Visible = 'off';
            app.TipodeImagenIncorrectoLabel.Text = 'Tipo de Imagen Incorrecto';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Glaucoma_diagnosis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end